classdef Six_DOF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Rigid6DOFUIFigure      matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        InputTab               matlab.ui.container.Tab
        MassEditFieldLabel     matlab.ui.control.Label
        MassEditField          matlab.ui.control.NumericEditField
        IxxEditFieldLabel      matlab.ui.control.Label
        IxxEditField           matlab.ui.control.NumericEditField
        IyyEditFieldLabel      matlab.ui.control.Label
        IyyEditField           matlab.ui.control.NumericEditField
        IzzEditFieldLabel      matlab.ui.control.Label
        IzzEditField           matlab.ui.control.NumericEditField
        IxyEditFieldLabel      matlab.ui.control.Label
        IxyEditField           matlab.ui.control.NumericEditField
        IxzEditFieldLabel      matlab.ui.control.Label
        IxzEditField           matlab.ui.control.NumericEditField
        IyzEditFieldLabel      matlab.ui.control.Label
        IyzEditField           matlab.ui.control.NumericEditField
        CoGXLabel              matlab.ui.control.Label
        CoGXEditField          matlab.ui.control.NumericEditField
        CoGYEditFieldLabel     matlab.ui.control.Label
        CoGYEditField          matlab.ui.control.NumericEditField
        CoGZEditFieldLabel     matlab.ui.control.Label
        CoGZEditField          matlab.ui.control.NumericEditField
        StiffnessLabel         matlab.ui.control.Label
        CoordinatesLabel       matlab.ui.control.Label
        K1xEditField_3Label    matlab.ui.control.Label
        K1xEditField           matlab.ui.control.NumericEditField
        K1yEditField_3Label    matlab.ui.control.Label
        K1yEditField           matlab.ui.control.NumericEditField
        K1zEditField_3Label    matlab.ui.control.Label
        K1zEditField           matlab.ui.control.NumericEditField
        K2xEditField_3Label    matlab.ui.control.Label
        K2xEditField           matlab.ui.control.NumericEditField
        K2yEditField_3Label    matlab.ui.control.Label
        K2yEditField           matlab.ui.control.NumericEditField
        K2zEditField_3Label    matlab.ui.control.Label
        K2zEditField           matlab.ui.control.NumericEditField
        K3xEditField_3Label    matlab.ui.control.Label
        K3xEditField           matlab.ui.control.NumericEditField
        K3yEditField_3Label    matlab.ui.control.Label
        K3yEditField           matlab.ui.control.NumericEditField
        K3zEditField_3Label    matlab.ui.control.Label
        K3zEditField           matlab.ui.control.NumericEditField
        K4xEditField_3Label    matlab.ui.control.Label
        K4xEditField           matlab.ui.control.NumericEditField
        K4yEditField_3Label    matlab.ui.control.Label
        K4yEditField           matlab.ui.control.NumericEditField
        K4zEditField_3Label    matlab.ui.control.Label
        K4zEditField           matlab.ui.control.NumericEditField
        K1xcoorEditFieldLabel  matlab.ui.control.Label
        K1xcoorEditField       matlab.ui.control.NumericEditField
        K2xcoorEditFieldLabel  matlab.ui.control.Label
        K2xcoorEditField       matlab.ui.control.NumericEditField
        K3xcoorEditFieldLabel  matlab.ui.control.Label
        K3xcoorEditField       matlab.ui.control.NumericEditField
        K4xcoorEditFieldLabel  matlab.ui.control.Label
        K4xcoorEditField       matlab.ui.control.NumericEditField
        K1ycoorEditFieldLabel  matlab.ui.control.Label
        K1ycoorEditField       matlab.ui.control.NumericEditField
        K2ycoorEditFieldLabel  matlab.ui.control.Label
        K2ycoorEditField       matlab.ui.control.NumericEditField
        K3ycoorEditFieldLabel  matlab.ui.control.Label
        K3ycoorEditField       matlab.ui.control.NumericEditField
        K4ycoorEditFieldLabel  matlab.ui.control.Label
        K4ycoorEditField       matlab.ui.control.NumericEditField
        K1zcoorEditFieldLabel  matlab.ui.control.Label
        K1zcoorEditField       matlab.ui.control.NumericEditField
        K2zcoorEditFieldLabel  matlab.ui.control.Label
        K2zcoorEditField       matlab.ui.control.NumericEditField
        K3zcoorEditFieldLabel  matlab.ui.control.Label
        K3zcoorEditField       matlab.ui.control.NumericEditField
        K4zcoorEditFieldLabel  matlab.ui.control.Label
        K4zcoorEditField       matlab.ui.control.NumericEditField
        ImportButton           matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        ResultTab              matlab.ui.container.Tab
        UITable2               matlab.ui.control.Table
        ContactdenizyazgacgmailcomLabel  matlab.ui.control.Label
        RigidbodymodeskineticenergydistributionLabel  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
        RUNButton              matlab.ui.control.Button
        UnitButtonGroup        matlab.ui.container.ButtonGroup
        NmkgButton             matlab.ui.control.RadioButton
        NmmkgButton            matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            format short
            m=app.MassEditField.Value
            Ixx=app.IxxEditField.Value
            Iyy=app.IyyEditField.Value
            Izz=app.IzzEditField.Value
            Ixy=app.IxyEditField.Value
            Ixz=app.IxzEditField.Value
            Iyz=app.IyzEditField.Value
            
            X=app.CoGXEditField.Value
            Y=app.CoGYEditField.Value
            Z=app.CoGZEditField.Value
            
            K1x=app.K1xEditField.Value
            K1y=app.K1yEditField.Value
            K1z=app.K1zEditField.Value
            
            K2x=app.K2xEditField.Value
            K2y=app.K2yEditField.Value
            K2z=app.K2zEditField.Value
            
            K3x=app.K3xEditField.Value
            K3y=app.K3yEditField.Value
            K3z=app.K3zEditField.Value
            
            K4x=app.K4xEditField.Value
            K4y=app.K4yEditField.Value
            K4z=app.K4zEditField.Value
            
            XK1=app.K1xcoorEditField.Value
            YK1=app.K1ycoorEditField.Value
            ZK1=app.K1zcoorEditField.Value
            
            XK2=app.K2xcoorEditField.Value
            YK2=app.K2ycoorEditField.Value
            ZK2=app.K2zcoorEditField.Value
            
            XK3=app.K3xcoorEditField.Value
            YK3=app.K3ycoorEditField.Value
            ZK3=app.K3zcoorEditField.Value
            
            XK4=app.K4xcoorEditField.Value
            YK4=app.K4ycoorEditField.Value
            ZK4=app.K4zcoorEditField.Value


            Coor=[XK1-X,YK1-Y,ZK1-Z;XK2-X,YK2-Y,ZK2-Z;XK3-X,YK3-Y,ZK3-Z;XK4-X,YK4-Y,ZK4-Z]
            K14=[K1x,K1y,K1z;K2x,K2y,K2z;K3x,K3y,K3z;K4x,K4y,K4z]
            It=[0,0,0,Ixx,-Ixy,-Ixz;0,0,0,-Ixy,Iyy,Iyz;0,0,0,-Ixz,-Iyz,Izz]

            
            if app.NmmkgButton.Value==1
                It=It./1E6
                X=X/1000
                Y=Y/1000
                Z=Z/1000
                Coor=Coor./1000
                K14=K14.*1000
                XK1=XK1/1000
                YK1=YK1/1000
                ZK1=ZK1/1000
                
                 XK2=XK2/1000
                YK2=YK2/1000
                ZK2=ZK2/1000
                
                 XK3=XK3/1000
                YK3=YK3/1000
                ZK3=ZK3/1000
                
                 XK4=XK4/1000
                YK4=YK4/1000
                ZK4=ZK4/1000
            end
            plot3(app.UIAxes,X,Y,Z,'b*',XK1,YK1,ZK1,'r*',XK2,YK2,ZK2,'r*',XK3,YK3,ZK3,'r*',XK4,YK4,ZK4,'r*',[X XK1],[Y YK1],[Z ZK1],'c-',[X XK2],[Y YK2],[Z ZK2],'c-',[X XK3],[Y YK3],[Z ZK3],'c-',[X XK4],[Y YK4],[Z ZK4],'c-')            

            M=[m,0,0,0,0,0;0,m,0,0,0,0;0,0,m,0,0,0;It]
            
            for i=1:1:4
                K{i}=[K14(i,1),0,0,0,K14(i,1)*Coor(i,3),-K14(i,1)*Coor(i,2);
                    0,K14(i,2),0,-K14(i,2)*Coor(i,3),0,K14(i,2)*Coor(i,1);
                    0,0,K14(i,3),K14(i,3)*Coor(i,2),-K14(i,3)*Coor(i,1),0;
                    0,-K14(i,2)*Coor(i,3),K14(i,3)*Coor(i,2),(K14(i,2)*(Coor(i,3)^2))+(K14(i,3)*(Coor(i,2)^2)),-K14(i,3)*Coor(i,1)*Coor(i,2),-K14(i,2)*Coor(i,1)*Coor(i,3)
                    K14(i,1)*Coor(i,3),0,-K14(i,3)*Coor(i,1),-K14(i,3)*Coor(i,1)*Coor(i,2) ,(K14(i,1)*(Coor(i,3)^2))+(K14(i,3)*(Coor(i,1)^2)),-K14(i,1)*Coor(i,3)*Coor(i,2)
                    -K14(i,1)*Coor(i,2),K14(i,2)*Coor(i,1),0,-K14(i,2)*Coor(i,1)*Coor(i,3),-K14(i,1)*Coor(i,3)*Coor(i,2),(K14(i,1)*(Coor(i,2)^2))+(K14(i,2)*(Coor(i,1)^2))]
            end
            K_tot=K{1}+K{2}+K{3}+K{4}
            
            [V,D] = eig(K_tot,M)
            omega=sqrt(diag(D))
            f=omega./(2.*pi)
            Energy=((V.^2).*diag(D))./(sum((V.^2).*diag(D)));
            data_final=[f';Energy.*100]

            app.UITable2.Data= num2cell(data_final)
            app.UITable2.RowName={'Frequency (Hz)';'X';'Y';'Z';'RX';'RY';'RZ'};
            app.UITable2.ColumnFormat={'bank','bank','bank','bank','bank','bank'}
            

            s_red = uistyle('BackgroundColor','red');
            s_bolt=uistyle('FontWeight','Bold')
            addStyle(app.UITable2,s_red,'row',1)
            addStyle(app.UITable2,s_bolt,'row',1)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file,path] = uiputfile('Data.xlsx','Save MDOF system');
            tbl = table(app.MassEditField.Value,app.IxxEditField.Value,app.IyyEditField.Value,app.IzzEditField.Value,app.IxyEditField.Value,app.IxzEditField.Value,app.IyzEditField.Value,app.CoGXEditField.Value,app.CoGYEditField.Value,app.CoGZEditField.Value,app.K1xEditField.Value,app.K1yEditField.Value,app.K1zEditField.Value,app.K2xEditField.Value,app.K2yEditField.Value,app.K2zEditField.Value,app.K3xEditField.Value,app.K3yEditField.Value,app.K3zEditField.Value,app.K4xEditField.Value,app.K4yEditField.Value,app.K4zEditField.Value,app.K1xcoorEditField.Value,app.K1ycoorEditField.Value,app.K1zcoorEditField.Value,app.K2xcoorEditField.Value,app.K2ycoorEditField.Value,app.K2zcoorEditField.Value,app.K3xcoorEditField.Value,app.K3ycoorEditField.Value,app.K3zcoorEditField.Value,app.K4xcoorEditField.Value,app.K4ycoorEditField.Value,app.K4zcoorEditField.Value, ...
                'VariableNames', {'Mass', 'Ixx', 'Iyy', 'Izz', 'Ixy', 'Ixz', 'Iyz','X','Y','Z','K1x','K1y','K1z','K2x','K2y','K2z','K3x','K3y','K3z','K4x','K4y','K4z','XK1','YK1','ZK1','XK2','YK2','ZK2','XK3','YK3','ZK3','XK4','YK4','ZK4'});
            writetable(tbl,strcat(path,file),'Sheet',1);
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [file,path] = uigetfile('Data.xlsx ','Save MDOF system');
            Import_M = readtable(strcat(path,file))
            T=table2array(Import_M);
           app.MassEditField.Value=T(1,1)
           app.IxxEditField.Value=T(1,2)
           app.IyyEditField.Value=T(1,3)
           app.IzzEditField.Value=T(1,4)
           app.IxyEditField.Value=T(1,5)
           app.IxzEditField.Value=T(1,6)
           app.IyzEditField.Value=T(1,7)
            
           app.CoGXEditField.Value=T(1,8)
           app.CoGYEditField.Value=T(1,9)
           app.CoGZEditField.Value=T(1,10)
    
           app.K1xEditField.Value=T(1,11)
           app.K1yEditField.Value=T(1,12)
           app.K1zEditField.Value=T(1,13)
            
           app.K2xEditField.Value=T(1,14)
           app.K2yEditField.Value=T(1,15)
           app.K2zEditField.Value=T(1,16)
            
           app.K3xEditField.Value=T(1,17)
           app.K3yEditField.Value=T(1,18)
           app.K3zEditField.Value=T(1,19)
            
           app.K4xEditField.Value=T(1,20)
           app.K4yEditField.Value=T(1,21)
           app.K4zEditField.Value=T(1,22)
            
           app.K1xcoorEditField.Value=T(1,23)
           app.K1ycoorEditField.Value=T(1,24)
           app.K1zcoorEditField.Value=T(1,25)
            
           app.K2xcoorEditField.Value=T(1,26)
           app.K2ycoorEditField.Value=T(1,27)
           app.K2zcoorEditField.Value=T(1,28)
            
           app.K3xcoorEditField.Value=T(1,29)
           app.K3ycoorEditField.Value=T(1,30)
           app.K3zcoorEditField.Value=T(1,31)
            
           app.K4xcoorEditField.Value=T(1,32)
           app.K4ycoorEditField.Value=T(1,33)
           app.K4zcoorEditField.Value=T(1,34)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Rigid6DOFUIFigure = uifigure('Visible', 'off');
            app.Rigid6DOFUIFigure.Name = 'Rigid6DOF';
            app.TabGroup = uitabgroup(app.Rigid6DOFUIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.MassEditFieldLabel = uilabel(app.InputTab);
            app.MassEditFieldLabel.Text = 'Mass ';
            app.MassEditField = uieditfield(app.InputTab, 'numeric');
            app.IxxEditFieldLabel = uilabel(app.InputTab);
            app.IxxEditFieldLabel.Text = 'Ixx ';
            app.IxxEditField = uieditfield(app.InputTab, 'numeric');
            app.IyyEditFieldLabel = uilabel(app.InputTab);
            app.IyyEditFieldLabel.Text = 'Iyy';
            app.IyyEditField = uieditfield(app.InputTab, 'numeric');
            app.IzzEditFieldLabel = uilabel(app.InputTab);
            app.IzzEditFieldLabel.Text = 'Izz';
            app.IzzEditField = uieditfield(app.InputTab, 'numeric');
            app.IxyEditFieldLabel = uilabel(app.InputTab);
            app.IxyEditFieldLabel.Text = 'Ixy';
            app.IxyEditField = uieditfield(app.InputTab, 'numeric');
            app.IxzEditFieldLabel = uilabel(app.InputTab);
            app.IxzEditFieldLabel.Text = 'Ixz';
            app.IxzEditField = uieditfield(app.InputTab, 'numeric');
            app.IyzEditFieldLabel = uilabel(app.InputTab);
            app.IyzEditFieldLabel.Text = 'Iyz';
            app.IyzEditField = uieditfield(app.InputTab, 'numeric');
            app.CoGXLabel = uilabel(app.InputTab);
            app.CoGXLabel.Text = 'CoG X';
            app.CoGXEditField = uieditfield(app.InputTab, 'numeric');
            app.CoGYEditFieldLabel = uilabel(app.InputTab);
            app.CoGYEditFieldLabel.Text = 'CoG Y';
            app.CoGYEditField = uieditfield(app.InputTab, 'numeric');
            app.CoGZEditFieldLabel = uilabel(app.InputTab);
            app.CoGZEditFieldLabel.Text = 'CoG Z';
            app.CoGZEditField = uieditfield(app.InputTab, 'numeric');
            app.StiffnessLabel = uilabel(app.InputTab);
            app.StiffnessLabel.Text = 'Stiffness -----------------------------------------------------------------------------------------------------------------------';
            app.CoordinatesLabel = uilabel(app.InputTab);
            app.CoordinatesLabel.Text = 'Coordinates ------------------------------------------------------------------------------------------------------------------';
            app.K1xEditField_3Label = uilabel(app.InputTab);
            app.K1xEditField_3Label.Text = 'K1x';
            app.K1xEditField = uieditfield(app.InputTab, 'numeric');
            app.K1yEditField_3Label = uilabel(app.InputTab);
            app.K1yEditField_3Label.Text = 'K1y';
            app.K1yEditField = uieditfield(app.InputTab, 'numeric');
            app.K1zEditField_3Label = uilabel(app.InputTab);
            app.K1zEditField_3Label.Text = 'K1z';
            app.K1zEditField = uieditfield(app.InputTab, 'numeric');
            app.K2xEditField_3Label = uilabel(app.InputTab);
            app.K2xEditField_3Label.Text = 'K2x';
            app.K2xEditField = uieditfield(app.InputTab, 'numeric');
            app.K2yEditField_3Label = uilabel(app.InputTab);
            app.K2yEditField_3Label.Text = 'K2y';
            app.K2yEditField = uieditfield(app.InputTab, 'numeric');
            app.K2zEditField_3Label = uilabel(app.InputTab);
            app.K2zEditField_3Label.Text = 'K2z';
            app.K2zEditField = uieditfield(app.InputTab, 'numeric');
            app.K3xEditField_3Label = uilabel(app.InputTab);
            app.K3xEditField_3Label.Text = 'K3x';
            app.K3xEditField = uieditfield(app.InputTab, 'numeric');
            app.K3yEditField_3Label = uilabel(app.InputTab);
            app.K3yEditField_3Label.Text = 'K3y';
            app.K3yEditField = uieditfield(app.InputTab, 'numeric');
            app.K3zEditField_3Label = uilabel(app.InputTab);
            app.K3zEditField_3Label.Text = 'K3z';
            app.K3zEditField = uieditfield(app.InputTab, 'numeric');
            app.K4xEditField_3Label = uilabel(app.InputTab);
            app.K4xEditField_3Label.Text = 'K4x';
            app.K4xEditField = uieditfield(app.InputTab, 'numeric');
            app.K4yEditField_3Label = uilabel(app.InputTab);
            app.K4yEditField_3Label.Text = 'K4y';
            app.K4yEditField = uieditfield(app.InputTab, 'numeric');
            app.K4zEditField_3Label = uilabel(app.InputTab);
            app.K4zEditField_3Label.Text = 'K4z';
            app.K4zEditField = uieditfield(app.InputTab, 'numeric');
            app.K1xcoorEditFieldLabel = uilabel(app.InputTab);
            app.K1xcoorEditFieldLabel.Text = 'K1x-coor';
            app.K1xcoorEditField = uieditfield(app.InputTab, 'numeric');
            app.K2xcoorEditFieldLabel = uilabel(app.InputTab);
            app.K2xcoorEditFieldLabel.Text = 'K2x-coor';
            app.K2xcoorEditField = uieditfield(app.InputTab, 'numeric');
            app.K3xcoorEditFieldLabel = uilabel(app.InputTab);
            app.K3xcoorEditFieldLabel.Text = 'K3x-coor';
            app.K3xcoorEditField = uieditfield(app.InputTab, 'numeric');
            app.K4xcoorEditFieldLabel = uilabel(app.InputTab);
            app.K4xcoorEditFieldLabel.Text = 'K4x-coor';
            app.K4xcoorEditField = uieditfield(app.InputTab, 'numeric');
            app.K1ycoorEditFieldLabel = uilabel(app.InputTab);
            app.K1ycoorEditFieldLabel.Text = 'K1y-coor';
            app.K1ycoorEditField = uieditfield(app.InputTab, 'numeric');
            app.K2ycoorEditFieldLabel = uilabel(app.InputTab);
            app.K2ycoorEditFieldLabel.Text = 'K2y-coor';
            app.K2ycoorEditField = uieditfield(app.InputTab, 'numeric');
            app.K3ycoorEditFieldLabel = uilabel(app.InputTab);
            app.K3ycoorEditFieldLabel.Text = 'K3y-coor';
            app.K3ycoorEditField = uieditfield(app.InputTab, 'numeric');
            app.K4ycoorEditFieldLabel = uilabel(app.InputTab);
            app.K4ycoorEditFieldLabel.Text = 'K4y-coor';
            app.K4ycoorEditField = uieditfield(app.InputTab, 'numeric');
            app.K1zcoorEditFieldLabel = uilabel(app.InputTab);
            app.K1zcoorEditFieldLabel.Text = 'K1z-coor';
            app.K1zcoorEditField = uieditfield(app.InputTab, 'numeric');
            app.K2zcoorEditFieldLabel = uilabel(app.InputTab);
            app.K2zcoorEditFieldLabel.Text = 'K2z-coor';
            app.K2zcoorEditField = uieditfield(app.InputTab, 'numeric');
            app.K3zcoorEditFieldLabel = uilabel(app.InputTab);
            app.K3zcoorEditFieldLabel.Text = 'K3z-coor';
            app.K3zcoorEditField = uieditfield(app.InputTab, 'numeric');
            app.K4zcoorEditFieldLabel = uilabel(app.InputTab);
            app.K4zcoorEditFieldLabel.Text = 'K4z-coor';
            app.K4zcoorEditField = uieditfield(app.InputTab, 'numeric');
            app.ImportButton = uibutton(app.InputTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.SaveButton = uibutton(app.InputTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ResultTab = uitab(app.TabGroup);
            app.ResultTab.Title = 'Result';
            app.UITable2 = uitable(app.ResultTab);
            app.UITable2.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'};
            app.UITable2.RowName = {};
            app.ContactdenizyazgacgmailcomLabel = uilabel(app.ResultTab);
            app.ContactdenizyazgacgmailcomLabel.Text = 'Contact: denizyazgac@gmail.com';
            app.RigidbodymodeskineticenergydistributionLabel = uilabel(app.ResultTab);
            app.RigidbodymodeskineticenergydistributionLabel.Text = 'Rigid body modes kinetic energy distribution (%) ';
            app.UIAxes = uiaxes(app.ResultTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.RUNButton = uibutton(app.ResultTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.UnitButtonGroup = uibuttongroup(app.ResultTab);
            app.UnitButtonGroup.Title = 'Unit';
            app.NmkgButton = uiradiobutton(app.UnitButtonGroup);
            app.NmkgButton.Text = 'N,m,kg';
            app.NmkgButton.Value = true;
            app.NmmkgButton = uiradiobutton(app.UnitButtonGroup);
            app.NmmkgButton.Text = 'N,mm,kg';
            % Show the figure after all components are created
            app.Rigid6DOFUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Six_DOF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Rigid6DOFUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Rigid6DOFUIFigure)
        end
    end
end