classdef Tuned_mass_damper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DOF_TMD_OptUIFigure           matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        MasskgEditFieldLabel          matlab.ui.control.Label
        MasskgEditField               matlab.ui.control.NumericEditField
        StiffnessNmEditFieldLabel     matlab.ui.control.Label
        StiffnessNmEditField          matlab.ui.control.NumericEditField
        DampingNmsEditFieldLabel      matlab.ui.control.Label
        DampingNmsEditField           matlab.ui.control.NumericEditField
        ReactionmasskgEditFieldLabel  matlab.ui.control.Label
        ReactionmasskgEditField       matlab.ui.control.NumericEditField
        TMDstiffnessNmEditFieldLabel  matlab.ui.control.Label
        TMDstiffnessNmEditField       matlab.ui.control.NumericEditField
        TMDdampingNmsEditFieldLabel   matlab.ui.control.Label
        TMDdampingNmsEditField        matlab.ui.control.NumericEditField
        StartHzEditFieldLabel         matlab.ui.control.Label
        StartHzEditField              matlab.ui.control.NumericEditField
        StopHzEditFieldLabel          matlab.ui.control.Label
        StopHzEditField               matlab.ui.control.NumericEditField
        CalculateTMDeffectCheckBox    matlab.ui.control.CheckBox
        NotreadyButton                matlab.ui.control.Button
        InfoTextArea                  matlab.ui.control.TextArea
        DenizYazgacLabel              matlab.ui.control.Label
        DeactiveButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NotreadyButton
        function NotreadyButtonPushed(app, event)
            m=app.MasskgEditField.Value;
            k=app.StiffnessNmEditField.Value;
            c=app.DampingNmsEditField.Value;
            f1=app.StartHzEditField.Value;
            f2=app.StopHzEditField.Value;
            vector=[f1:0.0001:f2];
            for j=1:1:length(vector)
                w=(2.*pi.*vector(j));
                FRF(j)=(1./ (   (k-(m.*(w.^2)))  +  (i.*w.*c) ));
                                
            end
            natural=sqrt(k/m)/(2*pi)
            
           plot(app.UIAxes,vector,abs(FRF))
           app.InfoTextArea.Value{1}=['Viscous damping ratio----% ',num2str(100*(c/(sqrt(m*k))))]
           app.InfoTextArea.Value{2}=['Natural frequency----',num2str(natural),' Hz']
           app.InfoTextArea.Value{3}=['TMD mass advise----',num2str(0.1*m)]
           app.InfoTextArea.Value{4}=['TMD stiffness advise----',num2str(((natural*2*pi).^2)*m*0.1)]
        end

        % Button down function: DOF_TMD_OptUIFigure
        function DOF_TMD_OptUIFigureButtonDown(app, event)
            if app.MasskgEditField.Value>0 & app.StiffnessNmEditField.Value>0 & app.StopHzEditField.Value>0 & app.StopHzEditField.Value>app.StartHzEditField.Value & app.DampingNmsEditField.Value >= 0
                app.NotreadyButton.BackgroundColor='g'
                app.NotreadyButton.Text='Ready...'
                app.NotreadyButton.Enable=1
            else 
                app.NotreadyButton.BackgroundColor='r'
                app.NotreadyButton.Text='Not ready'
                app.NotreadyButton.Enable=0
            end 
        end

        % Value changed function: CalculateTMDeffectCheckBox
        function CalculateTMDeffectCheckBoxValueChanged(app, event)
            value = app.CalculateTMDeffectCheckBox.Value;
            switch value
                case 1
                app.DeactiveButton.Enable=1
                app.ReactionmasskgEditField.Enable=1
                app.TMDdampingNmsEditField.Enable=1
                app.TMDstiffnessNmEditField.Enable=1
                app.DeactiveButton.Text='Calculate TMD'
                case 0
                app.DeactiveButton.Enable=0
                app.ReactionmasskgEditField.Enable=0
                app.TMDdampingNmsEditField.Enable=0
                app.TMDstiffnessNmEditField.Enable=0
                cla(app.UIAxes);
                app.NotreadyButtonPushed(event)
                app.DeactiveButton.Text='Deactive'
            end
        end

        % Button pushed function: DeactiveButton
        function DeactiveButtonPushed(app, event)
            m=app.MasskgEditField.Value;
            k=app.StiffnessNmEditField.Value;
            c=app.DampingNmsEditField.Value;
            f1=app.StartHzEditField.Value;
            f2=app.StopHzEditField.Value;
            vector=[f1:0.0001:f2];
                m2=app.ReactionmasskgEditField.Value;
                k2=app.TMDstiffnessNmEditField.Value;
                c2=app.TMDdampingNmsEditField.Value;
                M=[m 0; 0 m2];
                K=[k+k2 -k2; -k2 k2];
                C=[c+c2 -c2; -c2 c2];
                F=[1 ;0];
                
            for j=1:1:length(vector)
                w=(2.*pi.*vector(j));
                T=K-(((2.*pi.*vector(j)).^2).*M)+(i.*((2.*pi.*vector(j)).*C));
                R=inv(T)*F;
                R1(j)=R(1);
                                                
            end
            hold(app.UIAxes,'on')
            plot(app.UIAxes,vector,abs(R1))  
            hold(app.UIAxes,'off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DOF_TMD_OptUIFigure = uifigure('Visible', 'off');
            app.DOF_TMD_OptUIFigure.Name = '1DOF_TMD_Opt';
            app.DOF_TMD_OptUIFigure.ButtonDownFcn = createCallbackFcn(app, @DOF_TMD_OptUIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.DOF_TMD_OptUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'FRF (X/F)')
            app.MasskgEditFieldLabel = uilabel(app.DOF_TMD_OptUIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.DOF_TMD_OptUIFigure, 'numeric');
            app.StiffnessNmEditFieldLabel = uilabel(app.DOF_TMD_OptUIFigure);
            app.StiffnessNmEditFieldLabel.Text = 'Stiffness (N/m)';
            app.StiffnessNmEditField = uieditfield(app.DOF_TMD_OptUIFigure, 'numeric');
            app.DampingNmsEditFieldLabel = uilabel(app.DOF_TMD_OptUIFigure);
            app.DampingNmsEditFieldLabel.Text = 'Damping (N/m/s)';
            app.DampingNmsEditField = uieditfield(app.DOF_TMD_OptUIFigure, 'numeric');
            app.ReactionmasskgEditFieldLabel = uilabel(app.DOF_TMD_OptUIFigure);
            app.ReactionmasskgEditFieldLabel.Text = 'Reaction mass (kg)';
            app.ReactionmasskgEditField = uieditfield(app.DOF_TMD_OptUIFigure, 'numeric');
            app.ReactionmasskgEditField.Enable = 'off';
            app.TMDstiffnessNmEditFieldLabel = uilabel(app.DOF_TMD_OptUIFigure);
            app.TMDstiffnessNmEditFieldLabel.Text = 'TMD stiffness (N/m)';
            app.TMDstiffnessNmEditField = uieditfield(app.DOF_TMD_OptUIFigure, 'numeric');
            app.TMDstiffnessNmEditField.Enable = 'off';
            app.TMDdampingNmsEditFieldLabel = uilabel(app.DOF_TMD_OptUIFigure);
            app.TMDdampingNmsEditFieldLabel.Text = 'TMD damping (N/m/s)';
            app.TMDdampingNmsEditField = uieditfield(app.DOF_TMD_OptUIFigure, 'numeric');
            app.TMDdampingNmsEditField.Enable = 'off';
            app.StartHzEditFieldLabel = uilabel(app.DOF_TMD_OptUIFigure);
            app.StartHzEditFieldLabel.Text = 'Start  (Hz)';
            app.StartHzEditField = uieditfield(app.DOF_TMD_OptUIFigure, 'numeric');
            app.StopHzEditFieldLabel = uilabel(app.DOF_TMD_OptUIFigure);
            app.StopHzEditFieldLabel.Text = 'Stop (Hz)';
            app.StopHzEditField = uieditfield(app.DOF_TMD_OptUIFigure, 'numeric');
            app.CalculateTMDeffectCheckBox = uicheckbox(app.DOF_TMD_OptUIFigure);
            app.CalculateTMDeffectCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculateTMDeffectCheckBoxValueChanged, true);
            app.CalculateTMDeffectCheckBox.Text = 'Calculate TMD effect';
            app.NotreadyButton = uibutton(app.DOF_TMD_OptUIFigure, 'push');
            app.NotreadyButton.ButtonPushedFcn = createCallbackFcn(app, @NotreadyButtonPushed, true);
            app.NotreadyButton.Enable = 'off';
            app.NotreadyButton.Text = 'Not ready';
            app.InfoTextArea = uitextarea(app.DOF_TMD_OptUIFigure);
            app.InfoTextArea.Editable = 'off';
            app.DenizYazgacLabel = uilabel(app.DOF_TMD_OptUIFigure);
            app.DenizYazgacLabel.Text = 'Deniz Yazgac';
            app.DeactiveButton = uibutton(app.DOF_TMD_OptUIFigure, 'push');
            app.DeactiveButton.ButtonPushedFcn = createCallbackFcn(app, @DeactiveButtonPushed, true);
            app.DeactiveButton.Enable = 'off';
            app.DeactiveButton.Text = 'Deactive';
            % Show the figure after all components are created
            app.DOF_TMD_OptUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tuned_mass_damper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DOF_TMD_OptUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DOF_TMD_OptUIFigure)
        end
    end
end